classdef hwproject3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        SliderLabel          matlab.ui.control.Label
        Slider               matlab.ui.control.Slider
        SelectimageButton    matlab.ui.control.Button
        WebcamButton         matlab.ui.control.Button
        BonusButton          matlab.ui.control.Button
        RedEditFieldLabel    matlab.ui.control.Label
        RedEditField         matlab.ui.control.NumericEditField
        GreenEditFieldLabel  matlab.ui.control.Label
        GreenEditField       matlab.ui.control.NumericEditField
        BlueEditFieldLabel   matlab.ui.control.Label
        BlueEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        image 
        col
        row
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectimageButton
        function SelectimageButtonPushed(app, event)
            %selecting and reading an image
            [filename, filepath] = uigetfile({'*.jpg;*.png'}, 'Select File to Open');
            fullname = [filepath, filename];
            app.image = imread(fullname);
            
            size_img = size(app.image);
            %if size_img is big, decrease the image by 2 to speed up the process 
            if size_img(1)>700 || size_img(2)>700
                app.image = imresize(app.image, 0.5);
            end
            
            %display the image on the axis 
            imshow(app.image, 'parent', app.UIAxes)
            
            %show an additional figure to choose a pixel
            %ginput() does not work on the displayed axis
            %close the figure after 
            figure(1)
            imshow(app.image)
            title('Choose a pixel')
            [app.col, app.row] = ginput(1);
            close(1)
            col_int = uint8(app.col);
            row_int = uint8(app.row);
            
            %Display RGB value of the pixel
            app.RedEditField.Value = double(app.image(row_int, col_int, 1));
            app.GreenEditField.Value = double(app.image(row_int, col_int, 2));
            app.BlueEditField.Value = double(app.image(row_int, col_int, 3));
            

        end

        % Button pushed function: WebcamButton
        function WebcamButtonPushed(app, event)
            %obtaining access to a webcam and taking a snapshot with it
            camera = webcamlist;
            cam = webcam(camera{1, 1});
            app.image = snapshot(cam);
            
            %display the image on the axis
            imshow(app.image, 'parent', app.UIAxes)
            
            %show an additional figure to choose a pixel
            %ginput() does not work on the displayed axis
            %close the figure after 
            figure(1)
            imshow(app.image)
            title('Choose a pixel')
            [app.col, app.row] = ginput(1);
            close(1)
            col_int = uint8(app.col);
            row_int = uint8(app.row);
            
            %Display RGB value of the pixel
            app.RedEditField.Value = double(app.image(row_int, col_int, 1));
            app.GreenEditField.Value = double(app.image(row_int, col_int, 2));
            app.BlueEditField.Value = double(app.image(row_int, col_int, 3));


        end

        % Button pushed function: BonusButton
        function BonusButtonPushed(app, event)
            %Bonus button is used after Select image or Webcam buttons
            size_img = size(app.image);
            %obtain the red band of the image
            I_red = zeros(size_img,'uint8');
            I_red(:,:,1) = app.image(:,:,1);
            %obtain the blue band of the image
            I_blue = zeros(size_img,'uint8');
            I_blue(:,:,3) = app.image(:,:,3);
            
            %rotate the red and blue bands by 5 and -5 degrees
            A = imrotate(I_red, 5, 'bicubic', 'crop');
            B = imrotate(I_blue, -5, 'bicubic', 'crop');
            %overlay the images using alpha blending
            C = imfuse(app.image, A, 'blend', 'Scaling', 'joint');
            D = imfuse(B, C, 'blend', 'Scaling', 'joint');
            imshow(D, 'parent', app.UIAxes)
            
            %As a result, the ghost and glitch effects are obtained

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            size_img = size(app.image);
            
            %convert the image to double precision
            img_double = double(app.image);
            
            %the value of the chosen pixel
            color_center = squeeze(img_double(uint8(app.row), uint8(app.col), :))';
            
            %color radius depends on the value of the slider
            color_rad = value;
            
            %colors of interest enclosed by a sphere
            for i = 1:size_img(1)
                for j = 1:size_img(2)
                    if sqrt(sum((squeeze(img_double(i, j, :))' - color_center).^2)) > color_rad
                        img_double(i,j,:) = mean(squeeze(img_double(i, j, :)));       
                    end
                end   
            end
            img_int = uint8(img_double);
            imshow(img_int, 'parent', app.UIAxes)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.SelectimageButton = uibutton(app.UIFigure, 'push');
            app.SelectimageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectimageButtonPushed, true);
            app.SelectimageButton.Text = 'Select image';
            app.WebcamButton = uibutton(app.UIFigure, 'push');
            app.WebcamButton.ButtonPushedFcn = createCallbackFcn(app, @WebcamButtonPushed, true);
            app.WebcamButton.Text = 'Webcam';
            app.BonusButton = uibutton(app.UIFigure, 'push');
            app.BonusButton.ButtonPushedFcn = createCallbackFcn(app, @BonusButtonPushed, true);
            app.BonusButton.Text = 'Bonus';
            app.RedEditFieldLabel = uilabel(app.UIFigure);
            app.RedEditFieldLabel.Text = 'Red';
            app.RedEditField = uieditfield(app.UIFigure, 'numeric');
            app.RedEditField.Tag = 'Red';
            app.GreenEditFieldLabel = uilabel(app.UIFigure);
            app.GreenEditFieldLabel.Text = 'Green';
            app.GreenEditField = uieditfield(app.UIFigure, 'numeric');
            app.BlueEditFieldLabel = uilabel(app.UIFigure);
            app.BlueEditFieldLabel.Text = 'Blue';
            app.BlueEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hwproject3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end